classdef Cluster_Plotting_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClusterPlottingFigure          matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2_1                      matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2_4                      matlab.ui.control.UIAxes
        Image_left                     matlab.ui.control.UIAxes
        Image_right                    matlab.ui.control.UIAxes
        BestKBtn                       matlab.ui.control.Button
        selectDFCResBtn_1              matlab.ui.control.Button
        titleLabel                     matlab.ui.control.Label
        DFCmatLabel_3                  matlab.ui.control.Label
        e_InputDirEditField_1          matlab.ui.control.EditField
        BestKOptionalLabel             matlab.ui.control.Label
        selectDFCResBtn_2              matlab.ui.control.Button
        KmeansclusterLabel             matlab.ui.control.Label
        DFCmatLabel_2                  matlab.ui.control.Label
        e_InputDirEditField_2          matlab.ui.control.EditField
        PrefixLabel_2                  matlab.ui.control.Label
        KEditField                     matlab.ui.control.EditField
        DistancemethodDropDownLabel    matlab.ui.control.Label
        DistancemethodDropDown_2       matlab.ui.control.DropDown
        CLusterButton                  matlab.ui.control.Button
        DistancemethodDropDown_2Label  matlab.ui.control.Label
        DistancemethodDropDown_1       matlab.ui.control.DropDown
        PlottingLabel                  matlab.ui.control.Label
        SaveAsButton                   matlab.ui.control.Button
        ThebestKLabel                  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        State1Label                    matlab.ui.control.Label
        State2Label                    matlab.ui.control.Label
        State3Label                    matlab.ui.control.Label
        State4Label                    matlab.ui.control.Label
        State5Label                    matlab.ui.control.Label
        State6Label                    matlab.ui.control.Label
        State7Label                    matlab.ui.control.Label
        State8Label                    matlab.ui.control.Label
        State9Label                    matlab.ui.control.Label
        State10Label                   matlab.ui.control.Label
        State11Label                   matlab.ui.control.Label
        State12Label                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp % 主界面对象
        allState
        stateTransition
        K
        totalPage
        curPage
    end
    
    methods (Access = private)
        
        function Image_rightButtonDown(app, src, event)
            if app.curPage < app.totalPage % 判断是否小于最大页， 不符合则什么也不做
                % 1. 修改curPage的页码
                app.curPage = app.curPage + 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                
                % 2. 生成上一页的4状态
                % 2.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                NDN_plottingStates(app, app.e_InputDirEditField_2.Value)
            end
        end
        function Image_leftButtonDown(app, src, event)
            if app.curPage > 1 % 判断是否大于第一页， 不符合则什么也不做
                % 1. 修改curPage的页码
                app.curPage = app.curPage - 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                
                % 2. 生成上一页的4状态
                % 2.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                % 2.2 生成状态
                NDN_plottingStates(app, app.e_InputDirEditField_2.Value)
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mainApp = caller;
            assignin('base', ['Cluster_Plotting' '_app'], app);
            
            % 给app属性赋初始值
            app.curPage = 1;
            
        end

        % Close request function: ClusterPlottingFigure
        function ClusterPlottingFigureCloseRequest(app, event)
            app.mainApp.ClusteringPlottingButton.Enable = 'on';
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: selectDFCResBtn_1
        function selectDFCResBtn_1Pushed(app, event)
            app.ClusterPlottingFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            
            [filename, pathname] = uigetfile('*.*','Please select a DFC Res mat file');
            if filename ~= 0
                if~isequal(filename(end - 7 : end), '_all.mat')
                    app.mainApp.NaDyNetUIFigure.Visible = 'on';
                    app.ClusterPlottingFigure.Visible = 'on';
                    pause(0.1)
                    msg = ['The file name must be end with "_all.mat", but the file: ' filename ' you select is not accord with the rules'];
                    title = 'Error';
                    msgbox(msg, title, 'error');
                    return
                end
                
                
                matAddress = fullfile(pathname, filename);
                app.e_InputDirEditField_1.Value = string(matAddress);
                app.e_InputDirEditField_2.Value = string(matAddress);
            end
            
            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ClusterPlottingFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Value changed function: DistancemethodDropDown_1
        function DistancemethodDropDown_1ValueChanged(app, event)
            app.DistancemethodDropDown_2.Value = app.DistancemethodDropDown_1.Value;
        end

        % Callback function
        function subdirPrefixEditFieldValueChanged(app, event)
            value = app.subdirPrefixEditField.Value;
            if ~isempty(value)
                sublist = getSublistByPrefixed(app.e_InputDirEditField_1.Value, value);
                subnum = size(sublist,1);
                if subnum == 0
                    promptText = 'Error Input!';
                else
                    promptText = ['The number of target subdir is ' num2str(subnum)];
                end
                app.ThebestKLabel.Text = promptText;
            else
                app.ThebestKLabel.Text = "";
            end
            
        end

        % Button pushed function: selectDFCResBtn_2
        function selectDFCResBtn_2ButtonPushed(app, event)
            app.ClusterPlottingFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            
            [filename, pathname] = uigetfile('*.*','Please select a DFC Res mat file');
            if filename ~= 0
                if~isequal(filename(end - 7 : end), '_all.mat')
                    app.mainApp.NaDyNetUIFigure.Visible = 'on';
                    app.ClusterPlottingFigure.Visible = 'on';
                    pause(0.1)
                    msg = ['The file name must be end with "_all.mat", but the file: ' filename ' you select is not accord with the rules'];
                    title = 'Error';
                    msgbox(msg, title, 'error');
                    return
                end
                
                
                matAddress = fullfile(pathname, filename);
                app.e_InputDirEditField_2.Value = string(matAddress);
            end
            
            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ClusterPlottingFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            
            value = str2double(app.KEditField.Value);
            if value < 13 && value > 0
                app.K = value;
                
            else
                app.KEditField.Value = '';
                msg = 'The value of K should be in the range of 【1, 12】';
                title = 'Error Input';
                msgbox(msg, title, 'warning');
            end


        end

        % Button pushed function: BestKBtn
        function BestKBtnPushed(app, event)
            if isempty(app.e_InputDirEditField_1.Value)
                msg = ['The value of DFC res Result should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for " DFC res Result" ' ];
                msgbox(msg, title, 'warn')
                return
            end
            
            %% turn off
            app.DistancemethodDropDown_1.Enable = 'off';
            app.selectDFCResBtn_1.Enable = 'off';
            app.BestKBtn.Enable = 'off';
            %             pause(10)
            try
                inputFile = app.e_InputDirEditField_1.Value;
                dMethod = app.DistancemethodDropDown_1.Value;
                
                %                 app.K = NDN_bestK(inputFile, dMethod);
                app.K = 6;
                app.ThebestKLabel.Text = ['The best K:  ' num2str(app.K)];
                if app.K < 13 && app.K > 0
                    app.KEditField.Value = num2str(app.K);
                    app.totalPage = ceil(app.K / 4);
                end
                app.ThebestKLabel.Visible = "on";
                
                %% turn on
                app.DistancemethodDropDown_1.Enable = 'on';
                app.selectDFCResBtn_1.Enable = 'on';
                app.BestKBtn.Enable = 'on';
            catch ME
                %% turn on
                app.DistancemethodDropDown_1.Enable = 'on';
                app.selectDFCResBtn_1.Enable = 'on';
                app.BestKBtn.Enable = 'on';
                
                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is a error in computing kmeans ' ];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
            end
            
            
        end

        % Button pushed function: CLusterButton
        function CLusterButtonPushed(app, event)
            if isempty(app.e_InputDirEditField_2.Value)
                msg = ['The value of "DFC res Result" should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for " DFC res Result" ' ];
                msgbox(msg, title, 'warn')
                return
            end
            
            if isempty(app.KEditField.Value)
                msg = ['The value of "K" should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for "K" ' ];
                msgbox(msg, title, 'warn')
                return
            end
            
            %% turn off
            app.DistancemethodDropDown_2.Enable = 'off';
            app.selectDFCResBtn_2.Enable = 'off';
            app.CLusterButton.Enable = 'off';
            app.KEditField.Enable = 'off';
            
            try
                inputFile = app.e_InputDirEditField_2.Value;
                dMethod = app.DistancemethodDropDown_1.Value;
                
                for i = 1:12
                    app.(['State' num2str(i) 'Label']).Visible = 'off';
                end
                
                % 3.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                
                
                [app.allState, app.stateTransition] = NDN_kmeans(inputFile, dMethod, app.K);
                app.totalPage = ceil(app.K / 4);
                app.curPage = 1;
                NDN_plotting(app, app.e_InputDirEditField_2.Value)
                
                %% turn on
                app.DistancemethodDropDown_2.Enable = 'on';
                app.selectDFCResBtn_2.Enable = 'on';
                app.CLusterButton.Enable = 'on';
                app.KEditField.Enable = 'on';
                app.Label.Visible = 'on';
                app.Image_left.Visible = 'on';
                app.Image_right.Visible = 'on';
                app.SaveAsButton.Enable = 'on';
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                
                % 显示换页按钮
                rootPath = which('NDN');
                rootPath = fileparts(rootPath);
                leftImg = [rootPath filesep 'assets\left.png'];
                rightImg = [rootPath filesep 'assets\right.png'];
                imLeft = imread(leftImg);
                imRight = imread(rightImg);
                app.Image_left.XLim = [0.5, 30 + 0.5];
                app.Image_left.YLim = [0.5, 30 + 0.5];
                app.Image_right.XLim = [0.5, 30 + 0.5];
                app.Image_right.YLim = [0.5, 30 + 0.5];
                app.Image_left.XColor = 'none';
                app.Image_left.YColor = 'none';
                app.Image_right.XColor = 'none';
                app.Image_right.YColor = 'none';
                LImage = image(imLeft, 'Parent', app.Image_left);
                RLImage = imshow(imRight, 'Parent', app.Image_right);
                app.Image_right.Visible = "on";
                app.Image_left.Visible = "on";
                
                % 添加回调行为
                LImage.ButtonDownFcn = @app.Image_leftButtonDown;
                RLImage.ButtonDownFcn = @app.Image_rightButtonDown;
            catch ME
                %% turn on
                app.DistancemethodDropDown_2.Enable = 'on';
                app.selectDFCResBtn_2.Enable = 'on';
                app.CLusterButton.Enable = 'on';
                app.KEditField.Enable = 'on';
                
                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is an error in computing kmeans'];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
            end
            
        end

        % Callback function
        function Image_leftClicked(app, event)
            if app.curPage > 1
                % 1. 修改curPage的页码
                app.curPage = app.curPage - 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                %                 app.Image_right.Enable = 'on';
                
                % 2. 判断curPage == 1,等于的话那就要Enable = 'off'
                %                 if app.curPage == 1
                %                     app.Image_left.Enable = 'off';
                %                 end
                
                % 3. 生成上一页的4状态
                % 3.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                % 3.2 生成状态
                NDN_plottingStates(app, app.e_InputDirEditField_2.Value)
            end
        end

        % Callback function
        function Image_rightClicked(app, event)
            if app.curPage < app.totalPage
                % 1. 修改curPage的页码
                app.curPage = app.curPage + 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                %                 app.Image_left.Enable = 'on';
                
                % 2. 判断curPage == app.totalPage,等于的话那就要Enable = 'off'
                %                 if app.curPage == app.totalPage
                %                     app.Image_right.Enable = 'off';
                %                 end
                
                % 3. 生成下一页的4状态
                % 3.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                NDN_plottingStates(app, app.e_InputDirEditField_2.Value)
            end
            
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            app.ClusterPlottingFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            
            [savedDir] = uigetdir('*.*', 'Please select a saved directory');
            if  savedDir ~= 0
                try
                    NDN_savePlot_cluster(app, app.e_InputDirEditField_2.Value, savedDir)
                    
                catch ME
                    if ME.message
                        msg = ME.message;
                    else
                        msg = ['There is an error in saving'];
                    end
                    title = 'Error';
                    msgbox(msg, title, 'error');
                end
            end
            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ClusterPlottingFigure.Visible = 'on';%防止获取路径之后界面最小化
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClusterPlottingFigure = uifigure;
            app.ClusterPlottingFigure.Name = 'Cluster & Plotting';
            app.ClusterPlottingFigure.CloseRequestFcn = createCallbackFcn(app, @ClusterPlottingFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes2_1 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_1.XTick = [];
            app.UIAxes2_1.XTickLabel = '';
            app.UIAxes2_1.YTick = [];
            app.UIAxes2_1.YTickLabel = '';
            app.UIAxes2_1.Visible = 'off';
            app.UIAxes2_3 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.XTickLabel = '';
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.YTickLabel = '';
            app.UIAxes2_3.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes2_4 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_4.Visible = 'off';
            app.Image_left = uiaxes(app.ClusterPlottingFigure);
            app.Image_left.XTick = [];
            app.Image_left.XTickLabel = '';
            app.Image_left.YTick = [];
            app.Image_left.YTickLabel = '';
            app.Image_left.Visible = 'off';
            app.Image_right = uiaxes(app.ClusterPlottingFigure);
            app.Image_right.XTick = [];
            app.Image_right.XTickLabel = '';
            app.Image_right.YTick = [];
            app.Image_right.YTickLabel = '';
            app.Image_right.Visible = 'off';
            app.BestKBtn = uibutton(app.ClusterPlottingFigure, 'push');
            app.BestKBtn.ButtonPushedFcn = createCallbackFcn(app, @BestKBtnPushed, true);
            app.BestKBtn.Text = 'Best K';
            app.selectDFCResBtn_1 = uibutton(app.ClusterPlottingFigure, 'push');
            app.selectDFCResBtn_1.ButtonPushedFcn = createCallbackFcn(app, @selectDFCResBtn_1Pushed, true);
            app.selectDFCResBtn_1.Text = 'Choose';
            app.titleLabel = uilabel(app.ClusterPlottingFigure);
            app.titleLabel.Text = 'Clustering & Plotting';
            app.DFCmatLabel_3 = uilabel(app.ClusterPlottingFigure);
            app.DFCmatLabel_3.Text = 'DFC mat:';
            app.e_InputDirEditField_1 = uieditfield(app.ClusterPlottingFigure, 'text');
            app.e_InputDirEditField_1.Editable = 'off';
            app.BestKOptionalLabel = uilabel(app.ClusterPlottingFigure);
            app.BestKOptionalLabel.Text = ' 1. Best K (Optional)';
            app.selectDFCResBtn_2 = uibutton(app.ClusterPlottingFigure, 'push');
            app.selectDFCResBtn_2.ButtonPushedFcn = createCallbackFcn(app, @selectDFCResBtn_2ButtonPushed, true);
            app.selectDFCResBtn_2.Text = 'Choose';
            app.KmeansclusterLabel = uilabel(app.ClusterPlottingFigure);
            app.KmeansclusterLabel.Text = ' 2. Kmeans cluster';
            app.DFCmatLabel_2 = uilabel(app.ClusterPlottingFigure);
            app.DFCmatLabel_2.Text = 'DFC mat:';
            app.e_InputDirEditField_2 = uieditfield(app.ClusterPlottingFigure, 'text');
            app.e_InputDirEditField_2.Editable = 'off';
            app.PrefixLabel_2 = uilabel(app.ClusterPlottingFigure);
            app.PrefixLabel_2.Text = 'K:';
            app.KEditField = uieditfield(app.ClusterPlottingFigure, 'text');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.DistancemethodDropDownLabel = uilabel(app.ClusterPlottingFigure);
            app.DistancemethodDropDownLabel.Text = 'Distance method:';
            app.DistancemethodDropDown_2 = uidropdown(app.ClusterPlottingFigure);
            app.DistancemethodDropDown_2.Items = {'cityblock', 'cosine', 'sqeuclidean', 'correlation', 'hamming'};
            app.DistancemethodDropDown_2.Value = 'cityblock';
            app.CLusterButton = uibutton(app.ClusterPlottingFigure, 'push');
            app.CLusterButton.ButtonPushedFcn = createCallbackFcn(app, @CLusterButtonPushed, true);
            app.CLusterButton.Text = 'Cluster';
            app.DistancemethodDropDown_2Label = uilabel(app.ClusterPlottingFigure);
            app.DistancemethodDropDown_2Label.Text = 'Distance method:';
            app.DistancemethodDropDown_1 = uidropdown(app.ClusterPlottingFigure);
            app.DistancemethodDropDown_1.Items = {'cityblock', 'cosine', 'sqeuclidean', 'correlation', 'hamming'};
            app.DistancemethodDropDown_1.ValueChangedFcn = createCallbackFcn(app, @DistancemethodDropDown_1ValueChanged, true);
            app.DistancemethodDropDown_1.Value = 'cityblock';
            app.PlottingLabel = uilabel(app.ClusterPlottingFigure);
            app.PlottingLabel.Text = ' 3. Plotting';
            app.SaveAsButton = uibutton(app.ClusterPlottingFigure, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Enable = 'off';
            app.SaveAsButton.Text = 'Save As';
            app.ThebestKLabel = uilabel(app.ClusterPlottingFigure);
            app.ThebestKLabel.Visible = 'off';
            app.ThebestKLabel.Text = 'The best K:  ';
            app.Label = uilabel(app.ClusterPlottingFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '1/1';
            app.State1Label = uilabel(app.ClusterPlottingFigure);
            app.State1Label.Visible = 'off';
            app.State1Label.Text = '● State 1';
            app.State2Label = uilabel(app.ClusterPlottingFigure);
            app.State2Label.Visible = 'off';
            app.State2Label.Text = '● State 2';
            app.State3Label = uilabel(app.ClusterPlottingFigure);
            app.State3Label.Visible = 'off';
            app.State3Label.Text = '● State 3';
            app.State4Label = uilabel(app.ClusterPlottingFigure);
            app.State4Label.Visible = 'off';
            app.State4Label.Text = '● State 4';
            app.State5Label = uilabel(app.ClusterPlottingFigure);
            app.State5Label.Visible = 'off';
            app.State5Label.Text = '● State 5';
            app.State6Label = uilabel(app.ClusterPlottingFigure);
            app.State6Label.Visible = 'off';
            app.State6Label.Text = '● State 6';
            app.State7Label = uilabel(app.ClusterPlottingFigure);
            app.State7Label.Visible = 'off';
            app.State7Label.Text = '● State 7';
            app.State8Label = uilabel(app.ClusterPlottingFigure);
            app.State8Label.Visible = 'off';
            app.State8Label.Text = '● State 8';
            app.State9Label = uilabel(app.ClusterPlottingFigure);
            app.State9Label.Visible = 'off';
            app.State9Label.Text = '● State 9';
            app.State10Label = uilabel(app.ClusterPlottingFigure);
            app.State10Label.Visible = 'off';
            app.State10Label.Text = '● State 10';
            app.State11Label = uilabel(app.ClusterPlottingFigure);
            app.State11Label.Visible = 'off';
            app.State11Label.Text = '● State 11';
            app.State12Label = uilabel(app.ClusterPlottingFigure);
            app.State12Label.Visible = 'off';
            app.State12Label.Text = '● State 12';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Cluster_Plotting_GUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClusterPlottingFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClusterPlottingFigure)
        end
    end
end