classdef ISCAP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISCAPFigure                 matlab.ui.Figure
        RunBtn                      matlab.ui.control.Button
        InputDirBtn                 matlab.ui.control.Button
        GMBtn                       matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        ProgrssBarLabel             matlab.ui.control.Label
        titleLabel                  matlab.ui.control.Label
        SavedDirBtn                 matlab.ui.control.Button
        InputDirLabel               matlab.ui.control.Label
        e_InputDirEditField         matlab.ui.control.EditField
        GMMaskfileLabel             matlab.ui.control.Label
        e_GMmaskfileEditField       matlab.ui.control.EditField
        SubdirPrefixEditFieldLabel  matlab.ui.control.Label
        SubdirPrefixEditField       matlab.ui.control.EditField
        OutputDirLabel              matlab.ui.control.Label
        e_SavedDirEditField         matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        ParametersLabel             matlab.ui.control.Label
        runNameLabel                matlab.ui.control.Label
        runNameEditField            matlab.ui.control.EditField
        TmotLabel                   matlab.ui.control.Label
        TmotEditField               matlab.ui.control.EditField
        pPLabel                     matlab.ui.control.Label
        PpEditField                 matlab.ui.control.EditField
        KLabel                      matlab.ui.control.Label
        KEditField                  matlab.ui.control.EditField
        TRLabel                     matlab.ui.control.Label
        n_TREditField               matlab.ui.control.EditField
        pNLabel                     matlab.ui.control.Label
        PnEditField                 matlab.ui.control.EditField
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax %进度条对象
        isCreateAx %作为一个flag，判断进度条对象是否创建
        runned %作为一个flag，判断是否按过run
        title
        method
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, title, method)
            % 初始化editorField
            app.e_InputDirEditField.Value = pwd;
            [~, folderName, ~] = fileparts(app.e_InputDirEditField.Value);
            app.runNameEditField.Value = folderName;
            app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, [method '-Result']);
            % 初始化界面标识
            app.ISCAPFigure.Name = method;
            app.titleLabel.Text = title;
            % 初始化app属性
            app.title = title;
            app.method = method;
            app.mainApp=caller;
            app.isCreateAx = false;
            app.runned = false;

            assignin('base', [method '_app'], app);
            %             app.UI
        end

        % Close request function: ISCAPFigure
        function ISCAPFigureCloseRequest(app, event)
            app.mainApp.GMMethodButton.Enable = 'on';
            app.mainApp.GMVoxelMethodsDropDown.Enable = "on";
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: InputDirBtn
        function InputDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCAPFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            % 创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end


            [inputDir] = uigetdir('*.*','Please select an input directory');
            if inputDir ~= 0
                % saveDir
                app.e_InputDirEditField.Value = string(inputDir);
                app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, [app.method '-Result']);
                app.SubdirPrefixEditField.Value = '';
                app.Label.Text = '';
                % runName
                [~, folderName, ~] = fileparts(inputDir);
                app.runNameEditField.Value = folderName;
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ISCAPFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: SavedDirBtn
        function SavedDirBtnButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCAPFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [inputDir] = uigetdir('*.*','Please select a saved directory');
            if inputDir ~= 0
                app.e_SavedDirEditField.Value = string(inputDir);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ISCAPFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: GMBtn
        function GMBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCAPFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [filename, pathname] = uigetfile('*.*','Please select a Grey Matter mask file');
            if filename ~= 0
                maskAddress = fullfile(pathname, filename);
                app.e_GMmaskfileEditField.Value = string(maskAddress);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ISCAPFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Value changed function: SubdirPrefixEditField
        function SubdirPrefixEditFieldValueChanged(app, event)
            value = app.SubdirPrefixEditField.Value;
            if ~isempty(value)
                sublist = getSublistByPrefixed(app.e_InputDirEditField.Value, value);
                subnum = size(sublist,1);
                if subnum == 0 
                    promptText = 'Error Input!';
                else
                    promptText = ['The number of target subjects is ' num2str(subnum)];
                end
                app.Label.Text = promptText;
            else
                app.Label.Text = "";
            end

        end

        % Button pushed function: RunBtn
        function RunBtnPushed(app, event)
            if yjj_isUiobjNull(app)
                return 
            end

            try 
                if ~app.isCreateAx
                    initializeProgress(app)
                end
                patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ax.Title.String = 'initializing...';
                app.runned = true;
                app.ProgrssBarLabel.Visible = 'on';
                app.ax.Visible = 'on';
                drawnow
                yjj_ctrlUiobjEdfBtnCkb(app, 'off')
                
                inputDir = app.e_InputDirEditField.Value;
                prefix = app.SubdirPrefixEditField.Value;
                grayMatterMask = app.e_GMmaskfileEditField.Value;
                savedDir = app.e_SavedDirEditField.Value;

                params.runName = app.runNameEditField.Value;
                params.Tmot = str2double(app.TmotEditField.Value);
                params.K = str2double(app.KEditField.Value);
                params.Pp = str2double(app.PpEditField.Value);
                params.Pn = str2double(app.PnEditField.Value);
                params.method = app.method;
                if ~isempty(app.n_TREditField.Value)
                    params.TR = str2double(app.n_TREditField.Value);
                end
                ISCAP(inputDir, prefix, grayMatterMask, savedDir, params, app)
                msg = ['Congratulations,' app.method 'is finished'];
                title = 'Finished';
                msgbox(msg, title)
                yjj_ctrlUiobjEdfBtnCkb(app, 'on')
                app.ax.Title.String = ['Congratulations! ' app.method ' Result have been saved in "Output Dir"'];
            catch ME
                yjj_ctrlUiobjEdfBtnCkb(app, 'on')


                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is an error in computing ' app.method ];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error';
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISCAPFigure = uifigure;
            app.ISCAPFigure.Name = 'ISCAP';
            app.ISCAPFigure.CloseRequestFcn = createCallbackFcn(app, @ISCAPFigureCloseRequest, true);
            app.RunBtn = uibutton(app.ISCAPFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunBtn.Text = 'Run';
            app.InputDirBtn = uibutton(app.ISCAPFigure, 'push');
            app.InputDirBtn.ButtonPushedFcn = createCallbackFcn(app, @InputDirBtnPushed, true);
            app.InputDirBtn.Text = 'choose';
            app.GMBtn = uibutton(app.ISCAPFigure, 'push');
            app.GMBtn.ButtonPushedFcn = createCallbackFcn(app, @GMBtnPushed, true);
            app.GMBtn.Text = 'choose';
            app.Label = uilabel(app.ISCAPFigure);
            app.Label.Text = '';
            app.ProgrssBarLabel = uilabel(app.ISCAPFigure);
            app.ProgrssBarLabel.Visible = 'off';
            app.ProgrssBarLabel.Text = 'Progrss Bar:';
            app.titleLabel = uilabel(app.ISCAPFigure);
            app.titleLabel.Text = 'Inter-Subject Coactivation Pattern (ISCAP)';
            app.SavedDirBtn = uibutton(app.ISCAPFigure, 'push');
            app.SavedDirBtn.ButtonPushedFcn = createCallbackFcn(app, @SavedDirBtnButtonPushed, true);
            app.SavedDirBtn.Text = 'choose';
            app.InputDirLabel = uilabel(app.ISCAPFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.e_InputDirEditField = uieditfield(app.ISCAPFigure, 'text');
            app.e_InputDirEditField.Editable = 'off';
            app.GMMaskfileLabel = uilabel(app.ISCAPFigure);
            app.GMMaskfileLabel.Text = 'GM Mask file:';
            app.e_GMmaskfileEditField = uieditfield(app.ISCAPFigure, 'text');
            app.e_GMmaskfileEditField.Editable = 'off';
            app.SubdirPrefixEditFieldLabel = uilabel(app.ISCAPFigure);
            app.SubdirPrefixEditFieldLabel.Text = 'Subdir Prefix:';
            app.SubdirPrefixEditField = uieditfield(app.ISCAPFigure, 'text');
            app.SubdirPrefixEditField.ValueChangedFcn = createCallbackFcn(app, @SubdirPrefixEditFieldValueChanged, true);
            app.OutputDirLabel = uilabel(app.ISCAPFigure);
            app.OutputDirLabel.Text = 'Output Dir:';
            app.e_SavedDirEditField = uieditfield(app.ISCAPFigure, 'text');
            app.e_SavedDirEditField.Editable = 'off';
            app.Label_2 = uilabel(app.ISCAPFigure);
            app.Label_2.Text = '';
            app.ParametersLabel = uilabel(app.ISCAPFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.runNameLabel = uilabel(app.ISCAPFigure);
            app.runNameLabel.Text = 'runName:';
            app.runNameEditField = uieditfield(app.ISCAPFigure, 'text');
            app.TmotLabel = uilabel(app.ISCAPFigure);
            app.TmotLabel.Text = 'Tmot:';
            app.TmotEditField = uieditfield(app.ISCAPFigure, 'text');
            app.TmotEditField.Value = '0.5';
            app.pPLabel = uilabel(app.ISCAPFigure);
            app.pPLabel.Text = 'Pp';
            app.PpEditField = uieditfield(app.ISCAPFigure, 'text');
            app.PpEditField.Value = '100';
            app.KLabel = uilabel(app.ISCAPFigure);
            app.KLabel.Text = 'K:';
            app.KEditField = uieditfield(app.ISCAPFigure, 'text');
            app.KEditField.Value = '4';
            app.TRLabel = uilabel(app.ISCAPFigure);
            app.TRLabel.Text = 'TR:';
            app.n_TREditField = uieditfield(app.ISCAPFigure, 'text');
            app.pNLabel = uilabel(app.ISCAPFigure);
            app.pNLabel.Text = 'Pn';
            app.PnEditField = uieditfield(app.ISCAPFigure, 'text');
            app.PnEditField.Value = '100';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ISCAP_GUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISCAPFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISCAPFigure)
        end
    end
end